classdef educationalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SubmitButton           matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        FeedBackTextArea       matlab.ui.control.TextArea
        QuestionDropDownLabel  matlab.ui.control.Label
        QuestionDropDown       matlab.ui.control.DropDown
        EditField              matlab.ui.control.NumericEditField
        EditField2             matlab.ui.control.NumericEditField
        EditField3             matlab.ui.control.NumericEditField
        EditField4             matlab.ui.control.NumericEditField
        EditField5             matlab.ui.control.NumericEditField
        EditField6             matlab.ui.control.NumericEditField
        EditField7             matlab.ui.control.NumericEditField
        EditField8             matlab.ui.control.NumericEditField
        EditField9             matlab.ui.control.NumericEditField
        EditField10            matlab.ui.control.NumericEditField
        EditField_2            matlab.ui.control.EditField
        FinalGradeLabel        matlab.ui.control.Label
        FeedBackTextArea2      matlab.ui.control.TextArea
        FeedBackTextArea3      matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            b = app.QuestionDropDown.Value;
            a = app.TextArea.Value;
            marks = 0;
            if strcmp(b,'1) Make a simple if and else program.')
                    if ifchecker(a)
                        app.FeedBackTextArea.Value = 'You declared if right';
                        marks = marks + 5;
                    else
                        app.FeedBackTextArea.Value = 'You declared if wrong';
                    end
                    
                    if rem(else_end_checker(a),3) == 0 || rem(else_end_checker(a),2) == 0
                        app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value, 'Presence of end or else verified'];
                        marks = marks + 4;
                    else
                        app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value, 'Check for missing end or adding a condition infront of else or typing error with the decleration of else(or both)'];
                    end
                    
                    if semicolon_checker(a)
                        app.FeedBackTextArea2.Value = 'All uses of semicolon appears to be correct';
                        marks = marks + 1;
                    else
                        app.FeedBackTextArea2.Value = 'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if';
                    end
                app.EditField.Value = marks;
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{3};
                app.QuestionDropDown.Items{1} = '';
                app.TextArea.Value = '';
            elseif strcmp(b,'2) Write a simple if statement that returns true using arithmetic operators (do not use variables for the condition part and make sure you do not add spaces between the operator , should be like x==y not x == y)')
                if ifchecker(a)
                    app.FeedBackTextArea.Value = 'You declared if right';
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = 'You declared if wrong';
                end
                if semicolon_checker(a)
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'All uses of semicolon appears to be correct'];
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if'];
                end
                if answer2_checker(a)
                    app.FeedBackTextArea2.Value = 'Your condition appears to be right';
                    marks  = marks + 8;
                else 
                    app.FeedBackTextArea2.Value = 'Your condition returned false make sure you used the right operator';
                end
                app.EditField2.Value = marks;
                app.QuestionDropDown.Items{3} = '';
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{5};
                app.TextArea.Value = '';
            elseif strcmp(b,'3) write a simple if statement that generates a random number between 1 and 10, if 5 then it should return true the answer should only be a line of code don"t use variables')
                if ifchecker(a)
                    app.FeedBackTextArea.Value = 'You declared if right';
                    marks = marks + 2;
                else
                    app.FeedBackTextArea.Value = 'You declared if wrong';
                end
                if semicolon_checker(a)
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'All uses of semicolon appears to be correct'];
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if'];
                end
                
                if answer3_checker(a)
                    app.FeedBackTextArea2.Value = 'Your if randi(10) == 5 is right';
                    marks = marks + 7;
                else
                    app.FeedBackTextArea2.Value = 'You forgot to add an if randi(10) == 5 or used a wrong operator';
                end
                app.EditField3.Value = marks;
                app.QuestionDropDown.Items{5} = '';
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{7};
                app.TextArea.Value = '';
            elseif strcmp(b,'4) Write a simple if statement generates random numbers from 1 to 5 if 3 is found return true(don"t use variables and one line answer only).')
                if ifchecker(a)
                    app.FeedBackTextArea.Value = 'You declared if right';
                    marks = marks + 2;
                else
                    app.FeedBackTextArea.Value = 'You declared if wrong';
                end
                if semicolon_checker(a)
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'All uses of semicolon appears to be correct'];
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if'];
                end
                 
                if answer_4_checker(a)
                    app.FeedBackTextArea2.Value = 'Your if randi(5) == 3 is right';
                    marks = marks + 7;
                else
                    app.FeedBackTextArea2.Value = 'You forgot to add an if randi(5) == 3 or used a wrong operator';
                end
                app.EditField4.Value = marks;
                app.QuestionDropDown.Items{7} = '';
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{9};
                app.TextArea.Value = '';
            elseif strcmp(b,'5)Write a simple if statement that compares two numbers (not held in variables) and if number 1 is greater than number 2 return true.(One line answer)')
                [output1,output2] = answer_5_checker(a);
                if ifchecker(a)
                    app.FeedBackTextArea.Value = 'You declared if right';
                    marks = marks + 2;
                else
                    app.FeedBackTextArea.Value = 'You declared if wrong';
                end
                if semicolon_checker(a)
                    app.FeedBackTextArea3.Value = 'All uses of semicolon appears to be correct';
                    marks = marks + 1;
                else
                    app.FeedBackTextArea3.Value = 'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if';
                end
                if output1
                    app.FeedBackTextArea2.Value = 'The first number is bigger than the second that is correct';
                    marks = marks + 7;
                else
                    app.FeedBackTextArea2.Value = 'The first number should be greater than the second check your condition';
                end
                if output2
                    app.FeedBackTextArea2.Value = [app.FeedBackTextArea2.Value,'Make sure you didn"t use == or ~= for your condition'];
                end
                app.EditField5.Value = marks;
                app.QuestionDropDown.Items{9} = '';
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{11};
                app.TextArea.Value = '';
            elseif strcmp(b,'6)Write a simple if statement that compares two strings if they are the same use an inbuilt function to aid you(do not use input).')
                if ifchecker(a)
                    app.FeedBackTextArea.Value = 'You declared if right';
                    marks = marks + 2;
                else
                    app.FeedBackTextArea.Value = 'You declared if wrong';
                end
                if semicolon_checker(a)
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'All uses of semicolon appears to be correct'];
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if'];
                end
                if answer_6_checker(a)
                    app.FeedBackTextArea2.Value = 'Your strcmp seems fine';
                    marks = marks + 7;
                else
                    app.FeedBackTextArea2.Value = 'Check if you forgot to add strcmp in your condition';
                end
                app.EditField6.Value = marks;
                app.QuestionDropDown.Items{11} = '';
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{13};
                app.TextArea.Value = '';
            elseif strcmp(b,'7) Write a simple if else statement that takes an input from the user')
                [output1,output2] = answer_7_checker(a);
                if output1 == true
                    app.FeedBackTextArea.Value = 'Your if seems right';
                    marks = marks + 5;
                else
                    app.FeedBackTextArea.Value = 'You didn"t declare if';
                end
                if output2 == true
                    app.FeedBackTextArea2.Value = 'Your else seems right';
                    marks = marks + 5;
                else
                    app.FeedBackTextArea2.Value = 'You forgot about else or you added an elseif instead of an else';
                end
                app.EditField7.Value = marks;
                app.QuestionDropDown.Items{13} = '';
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{15};
                app.TextArea.Value = '';
            elseif strcmp(b,'8) Write a simple program that randomly picks numbers between 0 to 0.5 if numbers greater than 0.25 is found return true(One line answer)')
                if ifchecker(a)
                    app.FeedBackTextArea.Value = 'You declared if right';
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = 'You declared if wrong';
                end
                if semicolon_checker(a)
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'All uses of semicolon appears to be correct'];
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if'];
                end
                if answer_8_checker(a)
                    app.FeedBackTextArea2.Value = 'Your program is right';
                    marks = marks + 8;
                else
                    app.FeedBackTextArea2.Value = 'You forgot to add rand(0.5) in your if condition';
                end
                app.EditField8.Value = marks;
                app.QuestionDropDown.Items{15} = '';
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{17};
                app.TextArea.Value = '';
            elseif strcmp(b,'9)Write a simple if statement that generates a random integer from 1 to 10 and if it"s equal to 8 return true(One line answer)')
                if ifchecker(a)
                    app.FeedBackTextArea.Value = 'You declared if right';
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = 'You declared if wrong';
                end
                if semicolon_checker(a)
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'All uses of semicolon appears to be correct'];
                    marks = marks + 1;
                else
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if'];
                end
                if answer_9_checker(a)
                    app.FeedBackTextArea2.Value = 'Your program is right';
                    marks = marks + 8;
                else
                    app.FeedBackTextArea2.Value = 'You forgot to add if randi(10) == 8 in your if condition';
                end
                app.EditField9.Value = marks;
                app.QuestionDropDown.Items{17} = '';
                app.QuestionDropDown.Value = app.QuestionDropDown.Items{19};
                app.TextArea.Value = '';
            elseif strcmp(b,'10)Write a simple program that rolls a dice, if the dice output is greater than 3 return true (just write the statement), if the user rolls a six return true (just write statement), if the user rolls a 1 return true (just write the statement).')
                [output1,output2,output3] = answer_10_checker(a);
                if ifchecker(a)
                    app.FeedBackTextArea.Value = 'You declared if right';
                    marks = marks + 2;
                else
                    app.FeedBackTextArea.Value = 'You declared if wrong';
                end
                
                if semicolon_checker(a)
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'All uses of semicolon appears to be correct'];
                    marks = marks + 2;
                else
                    app.FeedBackTextArea.Value = [app.FeedBackTextArea.Value,'Check if you have forgotten to add a semicolon OR added a semicolon infront of end OR else OR if'];
                end
                if output1
                    app.FeedBackTextArea2.Value = 'Your randi(6) is right';
                    marks = marks + 2;
                else
                    app.FeedBackTextArea2.Value = 'You forgot to add if randi(6)==6 in your if condition';
                end
                if output2
                    app.FeedBackTextArea2.Value = [app.FeedBackTextArea2.Value,'Your randi(6)>3 is right'];
                    marks = marks + 2;
                else
                    app.FeedBackTextArea2.Value = [app.FeedBackTextArea2.Value,'You forgot to add if randi(6)>3 in your if condition'];
                end
                if output3
                    app.FeedBackTextArea3.Value = 'Your randi(6)==1 is right';
                    marks = marks + 2;
                else
                    app.FeedBackTextArea3.Value = 'You forgot to add if randi(6)==1 in your if condition';
                end
                app.EditField10.Value = marks;
                app.QuestionDropDown.Items{19} = '';
                app.TextArea.Value = '';
            end
            
            total = app.EditField.Value + app.EditField2.Value + app.EditField3.Value + app.EditField4.Value + app.EditField5.Value + app.EditField6.Value + app.EditField7.Value + app.EditField8.Value + app.EditField9.Value + app.EditField10.Value;
            if (total >=85) && (total <=100)
                app.EditField_2.Value='HD';
            elseif (total >=75) && (total <=84)
                app.EditField_2.Value='D';
            elseif (total >=65) && (total<=74)
                app.EditField_2.Value='C';
            elseif (total >=50) && (total<=64)
                app.EditField_2.Value='P';
            else
                app.EditField_2.Value='F';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.TextArea = uitextarea(app.UIFigure);
            app.FeedBackTextArea = uitextarea(app.UIFigure);
            app.FeedBackTextArea.Editable = 'off';
            app.QuestionDropDownLabel = uilabel(app.UIFigure);
            app.QuestionDropDownLabel.Text = 'Question';
            app.QuestionDropDown = uidropdown(app.UIFigure);
            app.QuestionDropDown.Items = {'1) Make a simple if and else program.', '', '2) Write a simple if statement that returns true using arithmetic operators (do not use variables for the condition part and make sure you do not add spaces between the operator , should be like x==y not x == y)', '', '3) write a simple if statement that generates a random number between 1 and 10, if 5 then it should return true the answer should only be a line of code don"t use variables', '', '4) Write a simple if statement generates random numbers from 1 to 5 if 3 is found return true(don"t use variables and one line answer only).', '', '5)Write a simple if statement that compares two numbers (not held in variables) and if number 1 is greater than number 2 return true.(One line answer)', '', '6)Write a simple if statement that compares two strings if they are the same use an inbuilt function to aid you(do not use input).', '', '7) Write a simple if else statement that takes an input from the user', '', '8) Write a simple program that randomly picks numbers between 0 to 0.5 if numbers greater than 0.25 is found return true(One line answer)', '', '9)Write a simple if statement that generates a random integer from 1 to 10 and if it"s equal to 8 return true(One line answer)', '', '10)Write a simple program that rolls a dice, if the dice output is greater than 3 return true (just write the statement), if the user rolls a six return true (just write statement), if the user rolls a 1 return true (just write the statement).', ''};
            app.QuestionDropDown.Value = '1) Make a simple if and else program.';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Editable = 'off';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Editable = 'off';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.Editable = 'off';
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5.Editable = 'off';
            app.EditField6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField6.Editable = 'off';
            app.EditField7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField7.Editable = 'off';
            app.EditField8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField8.Editable = 'off';
            app.EditField9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField9.Editable = 'off';
            app.EditField10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField10.Editable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.FinalGradeLabel = uilabel(app.UIFigure);
            app.FinalGradeLabel.Text = 'Final Grade';
            app.FeedBackTextArea2 = uitextarea(app.UIFigure);
            app.FeedBackTextArea2.Editable = 'off';
            app.FeedBackTextArea3 = uitextarea(app.UIFigure);
            app.FeedBackTextArea3.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = educationalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end