classdef projectile_motion_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnteranAngleEditFieldLabel    matlab.ui.control.Label
        EnteranAngleEditField         matlab.ui.control.NumericEditField
        EnteravelocityEditFieldLabel  matlab.ui.control.Label
        EnteravelocityEditField       matlab.ui.control.NumericEditField
        NewButton                     matlab.ui.control.Button
        GoButton                      matlab.ui.control.Button
        ScoreTurn1EditFieldLabel      matlab.ui.control.Label
        ScoreTurn1EditField           matlab.ui.control.NumericEditField
        RoundLabel                    matlab.ui.control.Label
        ScoreTurn2EditFieldLabel      matlab.ui.control.Label
        ScoreTurn2EditField           matlab.ui.control.NumericEditField
        ScoreTurn3EditFieldLabel      matlab.ui.control.Label
        ScoreTurn3EditField           matlab.ui.control.NumericEditField
        ScoreTurn4EditFieldLabel      matlab.ui.control.Label
        ScoreTurn4EditField           matlab.ui.control.NumericEditField
        ScoreTurn5EditFieldLabel      matlab.ui.control.Label
        ScoreTurn5EditField           matlab.ui.control.NumericEditField
        AverageEditFieldLabel         matlab.ui.control.Label
        AverageEditField              matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xinitial = 0;
        yinitial = 1.5;
        buttonhit = 0;
        xrand = randi(10);
        yrand = randi(100);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            plot(app.UIAxes,app.xrand,app.yrand,'rx','MarkerSize',10');
            axis(app.UIAxes,[0,inf,0,101]);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            x2rand = randi(10); % generate random integers for the x coordintate of the X plotter used to plot the x symbol on the graph
            y2rand = randi(100);% generate random integers for the y coordintate of the X plotter used to plot the x symbol on the graph
            hold(app.UIAxes,"on")% do not delete the previous X symbol
            [x,y] = Plotter_function(app.xinitial,app.yinitial,app.EnteravelocityEditField.Value,app.EnteranAngleEditField.Value); %get the displacemnt from the inputs theta and initial velocity
            plot(app.UIAxes,x,y); %plot the x and y in the Axis
            axis(app.UIAxes,[0,inf,0,100]); % using these limits the graph is displayed
            high_score = Check_if_close(x,x2rand,y,y2rand); % using the check if close function the score is determined
            app.Label.Text = '1'; % set the label to one at turn 1
            if app.buttonhit == 1 % when the buttonhit value reaches one do the following
                app.ScoreTurn1EditField.Value = high_score; % Display highscore in Score Turn Edit Field
                app.GoButton.Enable = false; % % Disable the Go button
                app.EnteranAngleEditField.Value = 0; % Set all the fields to empty
                app.EnteravelocityEditField.Value = 0; %Set the Velocity to zero
                pause(3); % pause for 3 seconds
                app.UIAxes.cla; % clear all the plots
                app.GoButton.Enable = true; %Enable the button
                hold(app.UIAxes,"on")% Hold on
                plot(app.UIAxes,x2rand,y2rand,'rx','MarkerSize',10'); %plot the X symbol
                axis(app.UIAxes,[0,inf,0,101]);%limits for displaying the x symbol
                [x2,y2] =  Plotter_function(app.xinitial,app.yinitial,app.EnteravelocityEditField.Value,app.EnteranAngleEditField.Value); %get the displacemnt from the inputs theta and initial velocity
                plot(app.UIAxes,x2,y2); %plot the x and y in the Axis
                axis(app.UIAxes,[0,inf,0,100]);% using these limits the graph is displayed
                high_score = Check_if_close(x2,x2rand,y2,y2rand);% using the check if close function the score is determined
                app.Label.Text = '2';  % set the label to one at turn 1
            elseif app.buttonhit == 2% when the buttonhit value reaches two do the following
                %from here the code does the same as the one above so i'm
                %i'm not commenting this 
                app.ScoreTurn2EditField.Value = high_score;
                app.GoButton.Enable = false;
                app.EnteranAngleEditField.Value = 0;
                app.EnteravelocityEditField.Value = 0;
                pause(3);
                app.UIAxes.cla;
                app.GoButton.Enable = true;
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x2rand,y2rand,'rx','MarkerSize',10');
                axis(app.UIAxes,[0,inf,0,101]);
                [x3,y3] =  Plotter_function(app.xinitial,app.yinitial,app.EnteravelocityEditField.Value,app.EnteranAngleEditField.Value);
                plot(app.UIAxes,x3,y3);
                axis(app.UIAxes,[0,inf,0,100]);
                high_score = Check_if_close(x3,x2rand,y3,y2rand);
                app.Label.Text = '3';
            elseif app.buttonhit == 3
                app.ScoreTurn3EditField.Value = high_score;
                app.GoButton.Enable = false;
                app.EnteranAngleEditField.Value = 0;
                app.EnteravelocityEditField.Value = 0;
                pause(3);
                app.UIAxes.cla;
                app.GoButton.Enable = true;
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x2rand,y2rand,'rx','MarkerSize',10');
                axis(app.UIAxes,[0,inf,0,101]);
                [x4,y4] =  Plotter_function(app.xinitial,app.yinitial,app.EnteravelocityEditField.Value,app.EnteranAngleEditField.Value);
                plot(app.UIAxes,x4,y4);
                axis(app.UIAxes,[0,inf,0,100]);
                high_score = Check_if_close(x4,x2rand,y4,y2rand);
                app.Label.Text = '4';
            elseif app.buttonhit == 4
                app.ScoreTurn4EditField.Value = high_score;
                app.GoButton.Enable = false;
                app.EnteranAngleEditField.Value = 0;
                app.EnteravelocityEditField.Value = 0;
                pause(3);
                app.UIAxes.cla;
                app.GoButton.Enable = true;
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x2rand,y2rand,'rx','MarkerSize',10');
                axis(app.UIAxes,[0,inf,0,101]);
                [x5,y5] =  Plotter_function(app.xinitial,app.yinitial,app.EnteravelocityEditField.Value,app.EnteranAngleEditField.Value);
                plot(app.UIAxes,x5,y5);
                axis(app.UIAxes,[0,inf,0,100]);
                high_score = Check_if_close(x5,x2rand,y5,y2rand);
                app.Label.Text = '5';
            elseif app.buttonhit == 5
                app.ScoreTurn5EditField.Value = high_score;
                app.EnteranAngleEditField.Value = 0;
                app.EnteravelocityEditField.Value = 0;
                app.buttonhit = 0; % after 5 turns set buttonhit to 0 to restart the gane
                app.AverageEditField.Visible = true;
                app.AverageEditField.Value = (app.ScoreTurn1EditField.Value + app.ScoreTurn2EditField.Value + app.ScoreTurn3EditField.Value + app.ScoreTurn4EditField.Value + app.ScoreTurn5EditField.Value)/5;
                %The average score calculator
                app.Label.Text = '1';
            %set the label to one again
            end
            app.buttonhit = app.buttonhit + 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnteranAngleEditFieldLabel = uilabel(app.UIFigure);
            app.EnteranAngleEditFieldLabel.Text = 'Enter an Angle';
            app.EnteranAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnteravelocityEditFieldLabel = uilabel(app.UIFigure);
            app.EnteravelocityEditFieldLabel.Text = 'Enter a velocity';
            app.EnteravelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ScoreTurn1EditFieldLabel = uilabel(app.UIFigure);
            app.ScoreTurn1EditFieldLabel.Text = 'Score Turn 1';
            app.ScoreTurn1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreTurn1EditField.Editable = 'off';
            app.RoundLabel = uilabel(app.UIFigure);
            app.RoundLabel.Text = 'Round';
            app.ScoreTurn2EditFieldLabel = uilabel(app.UIFigure);
            app.ScoreTurn2EditFieldLabel.Text = 'Score Turn 2';
            app.ScoreTurn2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreTurn2EditField.Editable = 'off';
            app.ScoreTurn3EditFieldLabel = uilabel(app.UIFigure);
            app.ScoreTurn3EditFieldLabel.Text = 'Score Turn 3';
            app.ScoreTurn3EditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreTurn3EditField.Editable = 'off';
            app.ScoreTurn4EditFieldLabel = uilabel(app.UIFigure);
            app.ScoreTurn4EditFieldLabel.Text = 'Score Turn 4';
            app.ScoreTurn4EditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreTurn4EditField.Editable = 'off';
            app.ScoreTurn5EditFieldLabel = uilabel(app.UIFigure);
            app.ScoreTurn5EditFieldLabel.Text = 'Score Turn 5';
            app.ScoreTurn5EditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreTurn5EditField.Editable = 'off';
            app.AverageEditFieldLabel = uilabel(app.UIFigure);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Guess the Distance')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 100];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectile_motion_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end